classdef LogisticRegressionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MNISTDigitsLearnerUIFigure    matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PickDigitsTab                 matlab.ui.container.Tab
        DigitViewerPanel              matlab.ui.container.Panel
        DigitPickerListBoxLabel       matlab.ui.control.Label
        DigitPickerListBox            matlab.ui.control.ListBox
        TrainTab                      matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        TrainFromScratchButton        matlab.ui.control.Button
        ContinuetrainingButton        matlab.ui.control.Button
        NumberOfErrorsEditFieldLabel  matlab.ui.control.Label
        NumberOfErrorsEditField       matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel    matlab.ui.control.Label
        LearningRateEditField         matlab.ui.control.NumericEditField
        ConfusionTab                  matlab.ui.container.Tab
        ConfusionMatrixPanel          matlab.ui.container.Panel
    end

    
    properties (Access = public)
        obj % Underlying object
    end
    
    properties (Access = public, Dependent)
        digits % Description
    end
    
    methods
        function value = get.digits(app)
            value = cellfun(@str2num, app.DigitPickerListBox.Value);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.obj = LogisticRegression(app);
            app.obj = app.obj.prepare_training_data;
        end

        % Button pushed function: TrainFromScratchButton
        function TrainFromScratchButtonPushed(app, event)
           
            app.obj = app.obj.train;
        end

        % Value changed function: DigitPickerListBox
        function DigitPickerListBoxValueChanged(app, event)
 app.obj = app.obj.prepare_training_data;
        end

        % Button pushed function: ContinuetrainingButton
        function ContinuetrainingButtonPushed(app, event)
            app.obj = app.obj.train(true);
        end

        % Callback function
        function PlotConfusionButtonPushed(app, event)
            app.obj.plot_confusion;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MNISTDigitsLearnerUIFigure = uifigure;
            app.MNISTDigitsLearnerUIFigure.Name = 'MNIST Digits Learner';
            app.TabGroup = uitabgroup(app.MNISTDigitsLearnerUIFigure);
            app.PickDigitsTab = uitab(app.TabGroup);
            app.PickDigitsTab.Title = 'Pick Digits';
            app.DigitViewerPanel = uipanel(app.PickDigitsTab);
            app.DigitViewerPanel.Title = 'Digit Viewer';
            app.DigitPickerListBoxLabel = uilabel(app.PickDigitsTab);
            app.DigitPickerListBoxLabel.Text = 'Digit Picker';
            app.DigitPickerListBox = uilistbox(app.PickDigitsTab);
            app.DigitPickerListBox.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.DigitPickerListBox.Multiselect = 'on';
            app.DigitPickerListBox.ValueChangedFcn = createCallbackFcn(app, @DigitPickerListBoxValueChanged, true);
            app.DigitPickerListBox.Tooltip = {'Pick your digits'};
            app.DigitPickerListBox.Value = {'0', '1', '2', '3'};
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.UIAxes = uiaxes(app.TrainTab);
            title(app.UIAxes, 'Training Progress')
            xlabel(app.UIAxes, 'Epochs')
            ylabel(app.UIAxes, 'Loss (Cross-Entropy)')
            app.TrainFromScratchButton = uibutton(app.TrainTab, 'push');
            app.TrainFromScratchButton.ButtonPushedFcn = createCallbackFcn(app, @TrainFromScratchButtonPushed, true);
            app.TrainFromScratchButton.Text = 'Train From Scratch';
            app.ContinuetrainingButton = uibutton(app.TrainTab, 'push');
            app.ContinuetrainingButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuetrainingButtonPushed, true);
            app.ContinuetrainingButton.Text = 'Continue training';
            app.NumberOfErrorsEditFieldLabel = uilabel(app.TrainTab);
            app.NumberOfErrorsEditFieldLabel.Text = 'Number Of Errors';
            app.NumberOfErrorsEditField = uieditfield(app.TrainTab, 'numeric');
            app.NumberOfErrorsEditField.Editable = 'off';
            app.NumberOfErrorsEditField.Value = 60000;
            app.LearningRateEditFieldLabel = uilabel(app.TrainTab);
            app.LearningRateEditFieldLabel.Text = 'LearningRate';
            app.LearningRateEditField = uieditfield(app.TrainTab, 'numeric');
            app.LearningRateEditField.Editable = 'off';
            app.ConfusionTab = uitab(app.TabGroup);
            app.ConfusionTab.Title = 'Confusion';
            app.ConfusionMatrixPanel = uipanel(app.ConfusionTab);
            app.ConfusionMatrixPanel.Title = 'Confusion Matrix';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LogisticRegressionApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MNISTDigitsLearnerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MNISTDigitsLearnerUIFigure)
        end
    end
end